classdef interfazEntrenamiento < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        NombrearchivodatosEditFieldLabel  matlab.ui.control.Label
        NombrearchivodatosEditField  matlab.ui.control.EditField
        BuscarButton                 matlab.ui.control.Button
        IntroduceunnombreLabel       matlab.ui.control.Label
        ComenzarentrenamientoButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BuscarButton
        function BuscarButtonPushed(app, event)
            %Busca el archivo en la carpeta MobileSensorData
            if exist("MobileSensorData/"+app.NombrearchivodatosEditField.Value+".mat", "file")
                app.IntroduceunnombreLabel.Text = "Archivo encontrado";
                app.IntroduceunnombreLabel.FontColor = "0.47,0.67,0.19";
                app.ComenzarentrenamientoButton.Enable=1;
            else
                app.IntroduceunnombreLabel.Text = "Archivo NO encontrado";
                app.IntroduceunnombreLabel.FontColor = "1.00,0.00,0.00";
                app.ComenzarentrenamientoButton.Enable=0;
            end
        end

        % Button pushed function: ComenzarentrenamientoButton
        function ComenzarentrenamientoButtonPushed(app, event)
            %Inicia el entrenamiento con el archivo de datos seleccionado
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NombrearchivodatosEditFieldLabel = uilabel(app.UIFigure);
            app.NombrearchivodatosEditFieldLabel.Text = 'Nombre archivo datos';
            app.NombrearchivodatosEditField = uieditfield(app.UIFigure, 'text');
            app.BuscarButton = uibutton(app.UIFigure, 'push');
            app.BuscarButton.ButtonPushedFcn = createCallbackFcn(app, @BuscarButtonPushed, true);
            app.BuscarButton.Text = 'Buscar';
            app.IntroduceunnombreLabel = uilabel(app.UIFigure);
            app.IntroduceunnombreLabel.Text = 'Introduce un nombre';
            app.ComenzarentrenamientoButton = uibutton(app.UIFigure, 'push');
            app.ComenzarentrenamientoButton.ButtonPushedFcn = createCallbackFcn(app, @ComenzarentrenamientoButtonPushed, true);
            app.ComenzarentrenamientoButton.Enable = 'off';
            app.ComenzarentrenamientoButton.Text = 'Comenzar entrenamiento';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfazEntrenamiento

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end