classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        InicioTab                    matlab.ui.container.Tab
        GridLayout17_2               matlab.ui.container.GridLayout
        GridLayout18_2               matlab.ui.container.GridLayout
        GridLayout20                 matlab.ui.container.GridLayout
        Image                        matlab.ui.control.Image
        Image2                       matlab.ui.control.Image
        GridLayout19                 matlab.ui.container.GridLayout
        ParaentrenarunaredpulseenlapestaaEntrenamientoredLabel  matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        EntrenamientoredTab          matlab.ui.container.Tab
        GridLayout                   matlab.ui.container.GridLayout
        GridLayout2                  matlab.ui.container.GridLayout
        EntrenarredButton            matlab.ui.control.Button
        GridLayout3                  matlab.ui.container.GridLayout
        ComenzarentrenamientoButton  matlab.ui.control.Button
        GridLayout4                  matlab.ui.container.GridLayout
        NombreRedLabel               matlab.ui.control.Label
        NumMovsLabel                 matlab.ui.control.Label
        NombrearchivoLabel           matlab.ui.control.Label
        GridLayout5                  matlab.ui.container.GridLayout
        IntroduceunnombreLabel       matlab.ui.control.Label
        numeroMovsEditFieldLabel     matlab.ui.control.Label
        numeroMovsEditField          matlab.ui.control.NumericEditField
        NombreRedEditFieldLabel      matlab.ui.control.Label
        NombreRedEditField           matlab.ui.control.EditField
        EditFieldLabel               matlab.ui.control.Label
        NombrearchivodatosEditField  matlab.ui.control.EditField
        GridLayout6                  matlab.ui.container.GridLayout
        BuscarButton                 matlab.ui.control.Button
        AceptarButton                matlab.ui.control.Button
        AadirButton                  matlab.ui.control.Button
        AceptarNombreRedButton       matlab.ui.control.Button
        TituloLabel                  matlab.ui.control.Label
        RESETButton                  matlab.ui.control.Button
        ClasificacinaccionesTab      matlab.ui.container.Tab
        GridLayout7                  matlab.ui.container.GridLayout
        GridLayout8                  matlab.ui.container.GridLayout
        CAPTURADEACCIONESDEMOVIMIENTOENTIEMPOREALLabel  matlab.ui.control.Label
        GridLayout14                 matlab.ui.container.GridLayout
        ComenzarcapturadeaccionesyclasificarButton  matlab.ui.control.Button
        GridLayout15                 matlab.ui.container.GridLayout
        ConectarButton               matlab.ui.control.Button
        EstablecerconexinconeldispositivoLabel  matlab.ui.control.Label
        premovimiento                matlab.ui.control.Label
        GridLayout17                 matlab.ui.container.GridLayout
        GridLayout16                 matlab.ui.container.GridLayout
        Label2                       matlab.ui.control.Label
        GridLayout9                  matlab.ui.container.GridLayout
        CLASIFICACINDEACCIONESPORARCHIVOSLabel  matlab.ui.control.Label
        GridLayout10                 matlab.ui.container.GridLayout
        GridLayout11                 matlab.ui.container.GridLayout
        GridLayout12                 matlab.ui.container.GridLayout
        ImportararchivoaclasificarLabel  matlab.ui.control.Label
        CargarDatosAClasificar       matlab.ui.control.Button
        GridLayout13                 matlab.ui.container.GridLayout
        ResultadomodaclasificacinTextAreaLabel  matlab.ui.control.Label
        ResultadomodaclasificacinTextArea  matlab.ui.control.TextArea
        GridLayout18                 matlab.ui.container.GridLayout
        ComprobarredButton           matlab.ui.control.Button
        PararealizarlaclasificacinesnecesarialaexistenciadeunaredLabel  matlab.ui.control.Label
        SeleccionarunmodelodelasredesdisponiblesDropDown  matlab.ui.control.DropDown
        SeleccionarunmodelodelasredesdisponiblesLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        numArchivos % numero de archivos para crear la red
        nombresArchivos % nombre de los archivos para crear la red
        popUpEntrenamientoWindow %pop up para comenzar el entrenamiento
        nombreRed % nombre de la red a crear
        existeRed % 1 true, 0 false
        net % redLSTM
        ResultadoModaClasificacion
        m
        path
    end
    
    methods (Access = private)
        
        function setNumArchivos(app,val)
           app.numArchivos = val;
        end
        
        function setNombresArchivos(app)
           app.nombresArchivos = cell(1,0);
        end
        function insertNombresArchivos(app,val,i)
            app.nombresArchivos{1,i}=val;
        end
        
        
        function setValuesReadyPart2(app)
            app.NumMovsLabel.Enable=0;
            app.numeroMovsEditField.Enable=0;
            app.AceptarButton.Enable=0;
            app.BuscarButton.Enable=1;
            app.NombrearchivoLabel.Enable=1;
            app.NombrearchivodatosEditField.Enable=1;
            app.IntroduceunnombreLabel.Enable=1;
            app.RESETButton.Enable=1;
            app.AceptarNombreRedButton.Enable=0;
            app.NombreRedEditField.Enable=0;
            app.NombreRedLabel.Enable=0;
            setNombresArchivos(app);
        end
        
        function setNombreRed(app, x)
            app.nombreRed = x;
        end
        
        function crearArchivoParaEntrenarRed(app)
            for i = 1:app.numArchivos
                nombre = app.nombresArchivos{1,i} + ".mat";
                load ("MobileSensorData/"+ nombre);
                NumDatos = size(Acceleration,1);
                
                Nexluir = 5; %Datos a excluiir
                X(:,1) = Acceleration.X(Nexluir+1:NumDatos-Nexluir);
                X(:,2) = Acceleration.Y(Nexluir+1:NumDatos-Nexluir);
                X(:,3) = Acceleration.Z(Nexluir+1:NumDatos-Nexluir);
                
                X=X';
                DatosY = size(X,2);
                for j=1:DatosY
                    Y{j} = app.nombresArchivos{1,i};
                end

                % Transformación a un categorical array
                Ynew = categorical(Y);
                
                 XTrain{1,i}=X;
                 YTrain{1,i}=Ynew;
                 clear("X");
                 clear("Y");
                 clear("Ynew");
            end
            nombreArchivo = app.nombreRed+"Train";
            app.folderExist("archivosTrain");
            save(["archivosTrain/"+nombreArchivo], 'XTrain', 'YTrain');
        end
        
        function net = entrenarRed(app, archivo, default)
            if default==1
                load(archivo);
                numClasses = 5;
            else
                load ("archivosTrain/"+archivo);
                numClasses = app.numArchivos;
            end
            

            numFeatures = 3;
            numHiddenUnits = 200;


            layers = [ ...
                sequenceInputLayer(numFeatures)
                lstmLayer(numHiddenUnits,'OutputMode','sequence')
                fullyConnectedLayer(numClasses)
                softmaxLayer
                classificationLayer];
            
            options = trainingOptions('adam', ...
                'MaxEpochs',60, ...
                'GradientThreshold',2, ...
                'Verbose',0, ...
                'Plots','training-progress');
            
            
            net = trainNetwork(XTrain,YTrain,layers,options);
        end
        
        function cargarRed(app,nombre)
             app.net = load ("redesEntrenadas/"+nombre);
        end
        
         function existRed(app)
             app.folderExist("redesEntrenadas");
             listaDeRedes = dir('redesEntrenadas/*.mat'); %Cromprueba si existen ficheros .mat
             [f,~] = size(listaDeRedes);
             if (f == 0) %Si esta vacia no se puede clasificar al no existir red
                app.existeRed = 0;
                uialert(app.UIFigure,'Para poder clasificar movimientos es necesario tener una red entrenada. Por favor, entrene una red.','Acceso denegado','CloseFcn',@(h,e) close(fig));
             else
                 app.existeRed = 1;
                 app.SeleccionarunmodelodelasredesdisponiblesLabel.Enable = 1;
                 app.SeleccionarunmodelodelasredesdisponiblesDropDown.Enable = 1;
                 app.EstablecerconexinconeldispositivoLabel.Enable =1;
                 app.ConectarButton.Enable =1;
                 app.ImportararchivoaclasificarLabel.Enable=1;
                 app.CargarDatosAClasificar.Enable =1;
                 for i = 1:f
                     nombre= listaDeRedes(i).name;
                     if ~any(ismember(app.SeleccionarunmodelodelasredesdisponiblesDropDown.Items,nombre))
                        app.SeleccionarunmodelodelasredesdisponiblesDropDown.Items = [app.SeleccionarunmodelodelasredesdisponiblesDropDown.Items nombre];
                     end
                 end
             end
        end
        
        function folderExist(~, nameFolder)
            
            if ~exist(nameFolder, 'dir')
                mkdir(nameFolder);
            end
            
        end
        
        function getIcon(app, Moda)
            switch Moda
                case "corriendo"
                   app.path = imread('icons/corriendo.png');
                case "saltando"
                   app.path = imread('icons/saltando.png');
                case "bailando"
                    app.path = imread('icons/bailando.png');
                case "andando"
                    app.path = imread('icons/andando.png');
            end
        end
        
         function actividad = getActividad(~, datos)
            switch datos
                case "Sitting"
                   actividad = 1;
                case "Standing"
                   actividad = 2;
                case "Walking"
                    actividad = 3;
                case "Running"
                    actividad = 4;
                case "Dancing"
                    actividad = 5;
                otherwise
                    actividad = -1;
            end
        end
        
        function writeInThingSpeak(~,data)
            switch data
                 case "Sitting"
                    thingSpeakWrite(1360842,'Fields',[4],'Values',{'Sitting'},'WriteKey',"K1PDV4OOAYWQY8LM");
                 case "Standing"
                    thingSpeakWrite(1360842,'Fields',[4],'Values',{'Standing'},'WriteKey',"K1PDV4OOAYWQY8LM");
                case "Walking"
                    thingSpeakWrite(1360842,'Fields',[4],'Values',{'Walking'},'WriteKey',"K1PDV4OOAYWQY8LM");
                case "Running"
                    thingSpeakWrite(1360842,'Fields',[4],'Values',{'Running'},'WriteKey',"K1PDV4OOAYWQY8LM");
                case "Dancing"
                    thingSpeakWrite(1360842,'Fields',[4],'Values',{'Dancing'},'WriteKey',"K1PDV4OOAYWQY8LM");
                otherwise
                    thingSpeakWrite(1360842,'Fields',[4],'Values',{'Otro'},'WriteKey',"K1PDV4OOAYWQY8LM");
            end
        end
   

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EntrenarredButton
        function EntrenarredButtonPushed(app, event)
            net = app.entrenarRed("HumanActivityTrain",1);
            app.folderExist("redesEntrenadas");
            save("redesEntrenadas/redDefault", 'net');
        end

        % Button pushed function: BuscarButton
        function BuscarButtonPushed(app, event)
             %Busca el archivo en la carpeta MobileSensorData
            if exist("MobileSensorData/"+app.NombrearchivodatosEditField.Value+".mat", "file")
                app.IntroduceunnombreLabel.Text = "Archivo encontrado";
                app.IntroduceunnombreLabel.FontColor = "0.47,0.67,0.19";
                app.AadirButton.Enable=1;
            else
                app.IntroduceunnombreLabel.Text = "Archivo NO encontrado";
                app.IntroduceunnombreLabel.FontColor = "1.00,0.00,0.00";
                app.AadirButton.Enable=0;
            end
        end

        % Button pushed function: AceptarButton
        function AceptarButtonPushed(app, event)

           setNumArchivos(app, app.numeroMovsEditField.Value);
           %de 1 a 5 movimientos
            setValuesReadyPart2(app);
        end

        % Button pushed function: AadirButton
        function AadirButtonPushed(app, event)
  
            [~,n] = size(app.nombresArchivos);
            if n < app.numArchivos
                insertNombresArchivos(app, app.NombrearchivodatosEditField.Value, n+1);
                app.IntroduceunnombreLabel.Text="Archivo añadido";
            end
            [~,n] = size(app.nombresArchivos);
            if n >= app.numArchivos %ya se han insertado los nombres de todos los archivos
                app.AadirButton.Enable=0;
                app.BuscarButton.Enable=0;
                app.NombrearchivoLabel.Enable=0;
                app.NombrearchivodatosEditField.Enable=0;
                app.NombreRedEditField.Enable=1;
                app.NombreRedLabel.Enable=1;
                app.AceptarNombreRedButton.Enable=1;     
            end
        end

        % Button pushed function: AceptarNombreRedButton
        function AceptarNombreRedButtonPushed(app, event)
            if app.NombreRedEditField.Value ~= ""
                app.NombreRedEditField.Enable=0;
                app.NombreRedLabel.Enable=0;
                app.AceptarNombreRedButton.Enable=0;
                app.IntroduceunnombreLabel.VerticalAlignment="bottom";
                app.IntroduceunnombreLabel.Text="Nombre válido";
                app.IntroduceunnombreLabel.FontColor="0.47,0.67,0.19";
                app.ComenzarentrenamientoButton.Enable=1;
            else
                app.IntroduceunnombreLabel.FontColor="1.00,0.00,0.00";
                app.IntroduceunnombreLabel.VerticalAlignment="bottom";
                app.IntroduceunnombreLabel.Text="Introduce un nombre para la red";
            end
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)

            app.numeroMovsEditField.Value=1;
            app.NumMovsLabel.Enable=1;
            app.numeroMovsEditField.Enable=1;
            app.AceptarButton.Enable=1;
            app.BuscarButton.Enable=0;
            app.AadirButton.Enable=0;
            app.NombrearchivoLabel.Enable=0;
            app.NombrearchivodatosEditField.Enable=0;
            app.IntroduceunnombreLabel.Enable=0;
            app.ComenzarentrenamientoButton.Enable=0;
            app.NombrearchivodatosEditField.Value="";
            app.IntroduceunnombreLabel.VerticalAlignment="top";
            app.IntroduceunnombreLabel.Text="Introduce un nombre";
            app.IntroduceunnombreLabel.FontColor="0.00,0.00,0.00";
            app.AceptarNombreRedButton.Enable=0;
            app.NombreRedEditField.Enable=0;
            app.NombreRedLabel.Enable=0;
            app.NombreRedEditField.Value="";
            app.RESETButton.Enable=0;
        end

        % Button pushed function: ComenzarentrenamientoButton
        function ComenzarentrenamientoButtonPushed(app, event)
            [~,n] = size(app.nombresArchivos);
            textonombres =app.nombresArchivos(1,1);
            for i = 2:n
               textonombres = textonombres + ", ";
               textonombres = textonombres+ app.nombresArchivos(1,i);
            end
            msg = "Se creará una red a partir de los archivos: "+ textonombres + ". Las acciones se nombrarán como el nombre del archivo. Si desea renombrar las acciones, renombre el archivo y súbalos de nuevo. ¿Quiere continuar? En caso de pulsar Cancelar, se borrarán los archivos introducidos";
            title = 'Comenzar a entrenar';
            fig = uifigure;
            selection = uiconfirm(fig,msg,title);
            switch selection
                case 'OK'
                    %se realizaría el entrenamiento
                    close(fig);
                    setNombreRed(app,app.NombreRedEditField.Value);
                    app.crearArchivoParaEntrenarRed;
                    net = app.entrenarRed(app.nombreRed+"Train",0);
                    app.folderExist("redesEntrenadas");
                    save(["redesEntrenadas/"+app.nombreRed], 'net');
                case 'Cancel'
                    close(fig);
                    app.NombrearchivodatosEditField.Value="";
                    app.IntroduceunnombreLabel.Text="Introduce un nombre";
                    app.IntroduceunnombreLabel.FontColor="0.00,0.00,0.00";
                    app.ComenzarentrenamientoButton.Enable=0;
                    setValuesReadyPart2(app);
            end
        end

        % Size changed function: ClasificacinaccionesTab
        function ClasificacinaccionesTabSizeChanged(app, event)
            app.ClasificacinaccionesTab.Position;
        end

        % Button pushed function: 
        % ComenzarcapturadeaccionesyclasificarButton
        function ComenzarcapturadeaccionesyclasificarButtonPushed(app, event)
           %% Definición de las claves de acceso del canal de registros
            ChannelIDClasificacion = 1309997;
            writeAPIKeyClasificacion = '8WC008J335WRPLI1';
           %% Definición de las claves de acceso del canal de registros
            ChannelIDSensores = 1360842;
            readAPIKeySensores = 'HO1LVJKK2ECEG5GE';
            writeAPIKeySensores = 'K1PDV4OOAYWQY8LM';
           %% Comprobar que existe una red entrenada antes de permitir clasificar
           if app.existeRed == 1
              
                %% Activar la habilitación para la captura de datos sensoriales durante el tiempo que dura el bucle
                app.m.Logging = 1;

                pause(2); % En mobile App sólo se admiten esperas con pause con 2 segundos como máximo
                pause(2); pause(2); pause(2); pause(2)

                %% Desactivar la captura
                app.m.Logging = 0;

                %% Recuperar los datos almacenados
                [aceleracion, taceleracion] = accellog(app.m);   % Logged acceleration data
                [vangular, tvangular] = angvellog(app.m);        % Logged angular velocity data    
                [orientacion, torientacion] = orientlog(app.m);  % Logged orientation data
                [magenetico, tmagnetico] = magfieldlog(app.m);   % Logged magnetic field data
                [posisicion, tposicion] = poslog(app.m);         % Logged position data

                
                %% Clasificación de la acción mediante el sensor aceleración
                [M,~] = size(aceleracion);
                
                if M == 0
                  disp('No se han capturado datos: repetir');
                else
                  X = aceleracion';
                  redLSTM = app.net.net;
                  Actividades = classify(redLSTM,X);
                  
                  t = table(Actividades',X(1,:)',X(2,:)',X(3,:)', 'VariableNames',{'Clasificación','X','Y','Z'});
                  % Create UI figure
                  fig = uifigure;
                  fig.Position(3:4) = [722 360];
                  % Create table UI component
                  uit = uitable(fig);
                  uit.Data = t;
                  uit.ColumnSortable = true;
                  uit.ColumnEditable = [false false false false];
                  uit.Position(3) = 290;
                 
                  % Generate timestamps for the data
                 % tStamps = datetime('now')-minutes(9):minutes(1):datetime('now');
                  % Write 10 values to each field of your channel along with timestamps
                 % for j=1:7
                  %   thingSpeakWrite(ChannelIDSensores,'Fields',[1,2,3,4,5,6,7],'Values',{Actividades(1,j)','Sitting'},'WriteKey',writeAPIKeySensores);
                   %   pause(15);
                 % end
                 % thingSpeakWrite(1373359,Actividades,TimeStamp,tStamps,'WriteKey','CM1SS02R3OCZC8JV');
                 
                 % thingSpeakWrite(ChannelIDSensores,'Fields',[1,2,3,4],'Values',{X(1,5)',X(2,5)',X(3,5)','Sitting'},'WriteKey',writeAPIKeySensores);
                  ax= uiaxes(fig);
                  ax.Position(1) = 315;
                  ax.XLabel.String = 'Tiempo relativo (s)';
                  %ax.Legend('Aceleración Y', 'Roll');
                  %yAngVel = vangular(:,2);
                  yAc = aceleracion(:,2);
                  roll = orientacion(:,3);
                  %plot(ax,tvangular,yAngVel,torientacion,roll);
                   plot(ax,taceleracion,yAc,torientacion,roll);
                  [~,tam] = size(Actividades(1,:));
                  writeInThingSpeak(app,mode(Actividades));
                  pause(15);
                  for j=1:tam
                      thingSpeakWrite(ChannelIDSensores,'Fields',[1,2,3],'Values',{X(1,j)',X(2,j)',X(3,j)'},'WriteKey',writeAPIKeySensores);
                      pause(15);
                  end
                end
                
                discardlogs(app.m);

           end
        end

        % Button pushed function: CargarDatosAClasificar
        function CargarDatosAClasificarButtonPushed(app, event)
             %% Definición de las claves de acceso del canal de registros
            ChannelIDSensores = 1360842;
            readAPIKeySensores = 'HO1LVJKK2ECEG5GE';
            writeAPIKeySensores = 'K1PDV4OOAYWQY8LM';
            %% Se da a elegir la carga del fichero deseado
            app.cargarRed(app.SeleccionarunmodelodelasredesdisponiblesDropDown.Value);
            if app.existeRed == 1
                [archivo,ruta]=uigetfile('*.mat','Abrir un archivo de datos');

                if archivo==0
                     return;
                else
                    dat_archivo=strcat(ruta,archivo);
                    archivoAClasificar = importdata(dat_archivo);
                    
                    %% Clasificación de la acción detectada mediante el registro de datos
                    % Cálculo del número de datos en aceleración
                    datos= size(archivoAClasificar.Acceleration,1);
                    if datos == 0
                        uialert(app.UIFigure,'No hay datos capturados.','Cargar otro archivo');
                    else
                        % Creación de un array para transferir los datos de Aceleración
                        x = zeros(datos,3);
                        x(:,1) = archivoAClasificar.Acceleration.X(1:datos);
                        x(:,2) = archivoAClasificar.Acceleration.Y(1:datos);
                        x(:,3) = archivoAClasificar.Acceleration.Z(1:datos);
                                              
                        % transponemos X para cambiar filas por columnas, de forma que ahora
                        % cada columna de X es un dato de aceleración.
                        X=x';
                        redLSTM = app.net.net;
                        Actividades = classify(redLSTM,X);
                        Moda = mode(Actividades);
                        app.ResultadomodaclasificacinTextArea.Value = Moda;
                        message = sprintf("Clasificación finalizada! \nEl resultado obtenido como la moda de la clasificación es:" + app.ResultadomodaclasificacinTextArea.Value);
                        uialert(app.UIFigure,message,'Clasificación finalizada','Icon', 'success');
                        pie(Actividades,app.ResultadomodaclasificacinTextArea.Value)
                        writeInThingSpeak(app,Moda);
                        pause(15);
                    end

                end
            end
        end

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)
             %% Borrado de objetos previos
                if exist('m','var')
                     clear m;
                end
                try
                    app.m = mobiledev;
                    app.cargarRed(app.SeleccionarunmodelodelasredesdisponiblesDropDown.Value);
                    app.premovimiento.Enable =1;
                    app.ComenzarcapturadeaccionesyclasificarButton.Enable=1;
                catch ME
                    warning(ME.message);
                    uialert(app.UIFigure,ME.message,'Warning', 'Icon', 'error');
            
                end
                %% Crear objeto 
                
               
        end

        % Value changed function: 
        % SeleccionarunmodelodelasredesdisponiblesDropDown
        function SeleccionarunmodelodelasredesdisponiblesDropDownValueChanged(app, event)
            app.nombreRed= app.SeleccionarunmodelodelasredesdisponiblesDropDown.Value;
            
          
        end

        % Button pushed function: ComprobarredButton
        function ComprobarredButtonPushed(app, event)
            app.existRed();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InicioTab = uitab(app.TabGroup);
            app.InicioTab.Title = 'Inicio';
            app.GridLayout17_2 = uigridlayout(app.InicioTab);
            app.GridLayout18_2 = uigridlayout(app.GridLayout17_2);
            app.GridLayout20 = uigridlayout(app.GridLayout18_2);
            app.Image = uiimage(app.GridLayout20);
            app.Image.ImageSource = 'HARD.png';
            app.Image2 = uiimage(app.GridLayout20);
            app.Image2.ImageSource = 'Human Actions Recognition Device.png';
            app.GridLayout19 = uigridlayout(app.GridLayout17_2);
            app.ParaentrenarunaredpulseenlapestaaEntrenamientoredLabel = uilabel(app.GridLayout19);
            app.ParaentrenarunaredpulseenlapestaaEntrenamientoredLabel.Text = 'Para entrenar una red, pulse en la pestaña "Entrenamiento red"';
            app.Label = uilabel(app.GridLayout19);
            app.Label.Text = 'Una vez tenga una red entrenada, pulse en la pestaña "Clasificación acciones" para comenzar la clasificación de acciones';
            app.EntrenamientoredTab = uitab(app.TabGroup);
            app.EntrenamientoredTab.Title = 'Entrenamiento red';
            app.GridLayout = uigridlayout(app.EntrenamientoredTab);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.EntrenarredButton = uibutton(app.GridLayout2, 'push');
            app.EntrenarredButton.ButtonPushedFcn = createCallbackFcn(app, @EntrenarredButtonPushed, true);
            app.EntrenarredButton.Text = 'Entrenar red';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.ComenzarentrenamientoButton = uibutton(app.GridLayout3, 'push');
            app.ComenzarentrenamientoButton.ButtonPushedFcn = createCallbackFcn(app, @ComenzarentrenamientoButtonPushed, true);
            app.ComenzarentrenamientoButton.Enable = 'off';
            app.ComenzarentrenamientoButton.Text = 'Comenzar entrenamiento';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.NombreRedLabel = uilabel(app.GridLayout4);
            app.NombreRedLabel.Enable = 'off';
            app.NombreRedLabel.Text = 'Nombre de la red:';
            app.NumMovsLabel = uilabel(app.GridLayout4);
            app.NumMovsLabel.Text = 'Número de movimientos:';
            app.NombrearchivoLabel = uilabel(app.GridLayout4);
            app.NombrearchivoLabel.Enable = 'off';
            app.NombrearchivoLabel.Text = 'Nombre de archivo:';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.IntroduceunnombreLabel = uilabel(app.GridLayout5);
            app.IntroduceunnombreLabel.Enable = 'off';
            app.IntroduceunnombreLabel.Text = 'Introduce un nombre';
            app.numeroMovsEditFieldLabel = uilabel(app.GridLayout5);
            app.numeroMovsEditFieldLabel.Text = 'numeroMovsEditField';
            app.numeroMovsEditField = uieditfield(app.GridLayout5, 'numeric');
            app.numeroMovsEditField.Limits = [1 5];
            app.numeroMovsEditField.Value = 1;
            app.NombreRedEditFieldLabel = uilabel(app.GridLayout5);
            app.NombreRedEditFieldLabel.Enable = 'off';
            app.NombreRedEditFieldLabel.Text = 'NombreRedEdit Field';
            app.NombreRedEditField = uieditfield(app.GridLayout5, 'text');
            app.NombreRedEditField.Enable = 'off';
            app.EditFieldLabel = uilabel(app.GridLayout5);
            app.EditFieldLabel.Text = 'Edit Field';
            app.NombrearchivodatosEditField = uieditfield(app.GridLayout5, 'text');
            app.NombrearchivodatosEditField.Enable = 'off';
            app.GridLayout6 = uigridlayout(app.GridLayout3);
            app.BuscarButton = uibutton(app.GridLayout6, 'push');
            app.BuscarButton.ButtonPushedFcn = createCallbackFcn(app, @BuscarButtonPushed, true);
            app.BuscarButton.Enable = 'off';
            app.BuscarButton.Text = 'Buscar';
            app.AceptarButton = uibutton(app.GridLayout6, 'push');
            app.AceptarButton.ButtonPushedFcn = createCallbackFcn(app, @AceptarButtonPushed, true);
            app.AceptarButton.Text = 'Aceptar';
            app.AadirButton = uibutton(app.GridLayout6, 'push');
            app.AadirButton.ButtonPushedFcn = createCallbackFcn(app, @AadirButtonPushed, true);
            app.AadirButton.Enable = 'off';
            app.AadirButton.Text = 'Añadir';
            app.AceptarNombreRedButton = uibutton(app.GridLayout6, 'push');
            app.AceptarNombreRedButton.ButtonPushedFcn = createCallbackFcn(app, @AceptarNombreRedButtonPushed, true);
            app.AceptarNombreRedButton.Enable = 'off';
            app.AceptarNombreRedButton.Text = 'Aceptar';
            app.TituloLabel = uilabel(app.GridLayout3);
            app.TituloLabel.Text = 'CREAR RED CON MOVIMIENTOS PROPIOS';
            app.RESETButton = uibutton(app.GridLayout3, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Enable = 'off';
            app.RESETButton.Text = 'RESET';
            app.ClasificacinaccionesTab = uitab(app.TabGroup);
            app.ClasificacinaccionesTab.SizeChangedFcn = createCallbackFcn(app, @ClasificacinaccionesTabSizeChanged, true);
            app.ClasificacinaccionesTab.Title = 'Clasificación acciones';
            app.GridLayout7 = uigridlayout(app.ClasificacinaccionesTab);
            app.GridLayout8 = uigridlayout(app.GridLayout7);
            app.CAPTURADEACCIONESDEMOVIMIENTOENTIEMPOREALLabel = uilabel(app.GridLayout8);
            app.CAPTURADEACCIONESDEMOVIMIENTOENTIEMPOREALLabel.Text = {'CAPTURA DE ACCIONES DE MOVIMIENTO '; 'EN TIEMPO REAL'};
            app.GridLayout14 = uigridlayout(app.GridLayout8);
            app.ComenzarcapturadeaccionesyclasificarButton = uibutton(app.GridLayout14, 'push');
            app.ComenzarcapturadeaccionesyclasificarButton.ButtonPushedFcn = createCallbackFcn(app, @ComenzarcapturadeaccionesyclasificarButtonPushed, true);
            app.ComenzarcapturadeaccionesyclasificarButton.Enable = 'off';
            app.ComenzarcapturadeaccionesyclasificarButton.Text = ' Comenzar captura de acciones y clasificar';
            app.GridLayout15 = uigridlayout(app.GridLayout14);
            app.ConectarButton = uibutton(app.GridLayout15, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Enable = 'off';
            app.ConectarButton.Text = 'Conectar';
            app.EstablecerconexinconeldispositivoLabel = uilabel(app.GridLayout15);
            app.EstablecerconexinconeldispositivoLabel.Enable = 'off';
            app.EstablecerconexinconeldispositivoLabel.Text = 'Establecer conexión con el dispositivo';
            app.premovimiento = uilabel(app.GridLayout14);
            app.premovimiento.Enable = 'off';
            app.premovimiento.Text = 'Prepárese para realizar el movimiento. Una vez se pulse el botón comenzar se iniciará la captura de datos.';
            app.GridLayout17 = uigridlayout(app.GridLayout14);
            app.GridLayout16 = uigridlayout(app.GridLayout8);
            app.Label2 = uilabel(app.GridLayout16);
            app.Label2.Text = 'Por favor, accede a Matlab desde el dispositivo móvil. Asegúrese de activar todos los sensores para poder capturar datos. ';
            app.GridLayout9 = uigridlayout(app.GridLayout7);
            app.CLASIFICACINDEACCIONESPORARCHIVOSLabel = uilabel(app.GridLayout9);
            app.CLASIFICACINDEACCIONESPORARCHIVOSLabel.Text = 'CLASIFICACIÓN DE ACCIONES POR ARCHIVOS';
            app.GridLayout10 = uigridlayout(app.GridLayout9);
            app.GridLayout11 = uigridlayout(app.GridLayout10);
            app.GridLayout12 = uigridlayout(app.GridLayout10);
            app.ImportararchivoaclasificarLabel = uilabel(app.GridLayout12);
            app.ImportararchivoaclasificarLabel.Enable = 'off';
            app.ImportararchivoaclasificarLabel.Text = 'Importar archivo a clasificar';
            app.CargarDatosAClasificar = uibutton(app.GridLayout12, 'push');
            app.CargarDatosAClasificar.ButtonPushedFcn = createCallbackFcn(app, @CargarDatosAClasificarButtonPushed, true);
            app.CargarDatosAClasificar.Enable = 'off';
            app.CargarDatosAClasificar.Text = {'Importar'; ''};
            app.GridLayout13 = uigridlayout(app.GridLayout10);
            app.ResultadomodaclasificacinTextAreaLabel = uilabel(app.GridLayout13);
            app.ResultadomodaclasificacinTextAreaLabel.Text = 'Resultado moda clasificación:';
            app.ResultadomodaclasificacinTextArea = uitextarea(app.GridLayout13);
            app.GridLayout18 = uigridlayout(app.GridLayout7);
            app.ComprobarredButton = uibutton(app.GridLayout18, 'push');
            app.ComprobarredButton.ButtonPushedFcn = createCallbackFcn(app, @ComprobarredButtonPushed, true);
            app.ComprobarredButton.Text = 'Comprobar red';
            app.PararealizarlaclasificacinesnecesarialaexistenciadeunaredLabel = uilabel(app.GridLayout18);
            app.PararealizarlaclasificacinesnecesarialaexistenciadeunaredLabel.Text = 'Para realizar la clasificación es necesaria la existencia de una red. Asegúrese de comprobarlo antes de continuar.';
            app.SeleccionarunmodelodelasredesdisponiblesDropDown = uidropdown(app.GridLayout18);
            app.SeleccionarunmodelodelasredesdisponiblesDropDown.Items = {};
            app.SeleccionarunmodelodelasredesdisponiblesDropDown.ValueChangedFcn = createCallbackFcn(app, @SeleccionarunmodelodelasredesdisponiblesDropDownValueChanged, true);
            app.SeleccionarunmodelodelasredesdisponiblesDropDown.Enable = 'off';
            app.SeleccionarunmodelodelasredesdisponiblesDropDown.Value = {};
            app.SeleccionarunmodelodelasredesdisponiblesLabel = uilabel(app.GridLayout18);
            app.SeleccionarunmodelodelasredesdisponiblesLabel.Enable = 'off';
            app.SeleccionarunmodelodelasredesdisponiblesLabel.Text = 'Seleccionar un modelo de las redes disponibles';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end