classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        EntrenamientoredTab          matlab.ui.container.Tab
        GridLayout                   matlab.ui.container.GridLayout
        GridLayout2                  matlab.ui.container.GridLayout
        EntrenarredButton            matlab.ui.control.Button
        GridLayout3                  matlab.ui.container.GridLayout
        ComenzarentrenamientoButton  matlab.ui.control.Button
        GridLayout4                  matlab.ui.container.GridLayout
        NombreRedLabel               matlab.ui.control.Label
        NumMovsLabel                 matlab.ui.control.Label
        NombrearchivoLabel           matlab.ui.control.Label
        GridLayout5                  matlab.ui.container.GridLayout
        IntroduceunnombreLabel       matlab.ui.control.Label
        numeroMovsEditFieldLabel     matlab.ui.control.Label
        numeroMovsEditField          matlab.ui.control.NumericEditField
        NombreRedEditFieldLabel      matlab.ui.control.Label
        NombreRedEditField           matlab.ui.control.EditField
        EditFieldLabel               matlab.ui.control.Label
        NombrearchivodatosEditField  matlab.ui.control.EditField
        GridLayout6                  matlab.ui.container.GridLayout
        BuscarButton                 matlab.ui.control.Button
        AceptarButton                matlab.ui.control.Button
        AadirButton                  matlab.ui.control.Button
        AceptarNombreRedButton       matlab.ui.control.Button
        TituloLabel                  matlab.ui.control.Label
        RESETButton                  matlab.ui.control.Button
        ReconocimientoaccionesTab    matlab.ui.container.Tab
        ComenzarcapturadeaccionesButton  matlab.ui.control.Button
        Label                        matlab.ui.control.Label
    end

    
    properties (Access = private)
        numArchivos % numero de archivos para crear la red
        nombresArchivos % nombre de los archivos para crear la red
        popUpEntrenamientoWindow %pop up para comenzar el entrenamiento
        nombreRed % nombre de la red a crear
    end
    
    methods (Access = private)
        
        function setNumArchivos(app,val)
           app.numArchivos = val;
        end
        
        function setNombresArchivos(app)
           app.nombresArchivos = cell(1,0);
        end
        function insertNombresArchivos(app,val,i)
            app.nombresArchivos{1,i}=val;
        end
        
        
        function setValuesReadyPart2(app)
            app.NumMovsLabel.Enable=0;
            app.numeroMovsEditField.Enable=0;
            app.AceptarButton.Enable=0;
            app.BuscarButton.Enable=1;
            app.NombrearchivoLabel.Enable=1;
            app.NombrearchivodatosEditField.Enable=1;
            app.IntroduceunnombreLabel.Enable=1;
            app.RESETButton.Enable=1;
            app.AceptarNombreRedButton.Enable=0;
            app.NombreRedEditField.Enable=0;
            app.NombreRedLabel.Enable=0;
            setNombresArchivos(app);
        end
        
        function setNombreRed(app, x)
            app.nombreRed = x;
        end
        
        function crearArchivoParaEntrenarRed(app)
            for i = 1:app.numArchivos
                nombre = app.nombresArchivos{1,i} + ".mat";
                load ("MobileSensorData/"+ nombre);
                NumDatos = size(Acceleration,1);
                
                Nexluir = 5; %Datos a excluiir
                X(:,1) = Acceleration.X(Nexluir+1:NumDatos-Nexluir);
                X(:,2) = Acceleration.Y(Nexluir+1:NumDatos-Nexluir);
                X(:,3) = Acceleration.Z(Nexluir+1:NumDatos-Nexluir);
                
                X=X';
                DatosY = size(X,2);
                for j=1:DatosY
                    Y{j} = app.nombresArchivos{1,i};
                end

                % Transformación a un categorical array
                Ynew = categorical(Y);
                
                 XTrain{1,i}=X;
                 YTrain{1,i}=Ynew;
                 clear("X");
                 clear("Y");
                 clear("Ynew");
            end
            nombreArchivo = app.nombreRed+"Train";
            app.folderExist("archivosTrain");
            save(["archivosTrain/"+nombreArchivo], 'XTrain', 'YTrain');
        end
        
        function net = entrenarRed(app, archivo, default)
            if default==1
                load(archivo);
                numClasses = 5;
            else
                load ("archivosTrain/"+archivo);
                numClasses = app.numArchivos;
            end
            

            numFeatures = 3;
            numHiddenUnits = 200;


            layers = [ ...
                sequenceInputLayer(numFeatures)
                lstmLayer(numHiddenUnits,'OutputMode','sequence')
                fullyConnectedLayer(numClasses)
                softmaxLayer
                classificationLayer];
            
            options = trainingOptions('adam', ...
                'MaxEpochs',60, ...
                'GradientThreshold',2, ...
                'Verbose',0, ...
                'Plots','training-progress');
            
            
            net = trainNetwork(XTrain,YTrain,layers,options);
        end
        
        function folderExist(app, nameFolder)
            
            if ~exist(nameFolder, 'dir')
                mkdir(nameFolder);
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EntrenarredButton
        function EntrenarredButtonPushed(app, event)
            net = app.entrenarRed("HumanActivityTrain",1);
            app.folderExist("redesEntrenadas");
            save("redesEntrenadas/redDefault", 'net');
        end

        % Button pushed function: BuscarButton
        function BuscarButtonPushed(app, event)
             %Busca el archivo en la carpeta MobileSensorData
            if exist("MobileSensorData/"+app.NombrearchivodatosEditField.Value+".mat", "file")
                app.IntroduceunnombreLabel.Text = "Archivo encontrado";
                app.IntroduceunnombreLabel.FontColor = "0.47,0.67,0.19";
                app.AadirButton.Enable=1;
            else
                app.IntroduceunnombreLabel.Text = "Archivo NO encontrado";
                app.IntroduceunnombreLabel.FontColor = "1.00,0.00,0.00";
                app.AadirButton.Enable=0;
            end
        end

        % Button pushed function: AceptarButton
        function AceptarButtonPushed(app, event)

           setNumArchivos(app, app.numeroMovsEditField.Value);
           %de 1 a 5 movimientos
            setValuesReadyPart2(app);
        end

        % Button pushed function: AadirButton
        function AadirButtonPushed(app, event)
  
            [~,n] = size(app.nombresArchivos);
            if n < app.numArchivos
                insertNombresArchivos(app, app.NombrearchivodatosEditField.Value, n+1);
                app.IntroduceunnombreLabel.Text="Archivo añadido";
            end
            [~,n] = size(app.nombresArchivos);
            if n >= app.numArchivos %ya se han insertado los nombres de todos los archivos
                app.AadirButton.Enable=0;
                app.BuscarButton.Enable=0;
                app.NombrearchivoLabel.Enable=0;
                app.NombrearchivodatosEditField.Enable=0;
                app.NombreRedEditField.Enable=1;
                app.NombreRedLabel.Enable=1;
                app.AceptarNombreRedButton.Enable=1;     
            end
        end

        % Button pushed function: AceptarNombreRedButton
        function AceptarNombreRedButtonPushed(app, event)
            if app.NombreRedEditField.Value ~= ""
                app.NombreRedEditField.Enable=0;
                app.NombreRedLabel.Enable=0;
                app.AceptarNombreRedButton.Enable=0;
                app.IntroduceunnombreLabel.VerticalAlignment="bottom";
                app.IntroduceunnombreLabel.Text="Nombre válido";
                app.IntroduceunnombreLabel.FontColor="0.47,0.67,0.19";
                app.ComenzarentrenamientoButton.Enable=1;
            else
                app.IntroduceunnombreLabel.FontColor="1.00,0.00,0.00";
                app.IntroduceunnombreLabel.VerticalAlignment="bottom";
                app.IntroduceunnombreLabel.Text="Introduce un nombre para la red";
            end
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)

            app.numeroMovsEditField.Value=1;
            app.NumMovsLabel.Enable=1;
            app.numeroMovsEditField.Enable=1;
            app.AceptarButton.Enable=1;
            app.BuscarButton.Enable=0;
            app.AadirButton.Enable=0;
            app.NombrearchivoLabel.Enable=0;
            app.NombrearchivodatosEditField.Enable=0;
            app.IntroduceunnombreLabel.Enable=0;
            app.ComenzarentrenamientoButton.Enable=0;
            app.NombrearchivodatosEditField.Value="";
            app.IntroduceunnombreLabel.VerticalAlignment="top";
            app.IntroduceunnombreLabel.Text="Introduce un nombre";
            app.IntroduceunnombreLabel.FontColor="0.00,0.00,0.00";
            app.AceptarNombreRedButton.Enable=0;
            app.NombreRedEditField.Enable=0;
            app.NombreRedLabel.Enable=0;
            app.NombreRedEditField.Value="";
            app.RESETButton.Enable=0;
        end

        % Button pushed function: ComenzarentrenamientoButton
        function ComenzarentrenamientoButtonPushed(app, event)
            [~,n] = size(app.nombresArchivos);
            textonombres =app.nombresArchivos(1,1);
            for i = 2:n
               textonombres = textonombres + ", ";
               textonombres = textonombres+ app.nombresArchivos(1,i);
            end
            msg = "Se creará una red a partir de los archivos: "+ textonombres + ". Las acciones se nombrarán como el nombre del archivo. Si desea renombrar las acciones, renombre el archivo y súbalos de nuevo. ¿Quiere continuar? En caso de pulsar Cancelar, se borrarán los archivos introducidos";
            fig = uifigure;
            title = 'Comenzar a entrenar';
            selection = uiconfirm(fig,msg,title,'CloseFcn',@(h,e) close(fig));
            
           if selection == 'OK'
               %se realizaría el entrenamiento
               setNombreRed(app,app.NombreRedEditField.Value);
               app.crearArchivoParaEntrenarRed;
               net = app.entrenarRed(app.nombreRed+"Train",0);
               app.folderExist("redesEntrenadas");
               save(["redesEntrenadas/"+app.nombreRed], 'net');
            else
                app.NombrearchivodatosEditField.Value="";
                app.IntroduceunnombreLabel.Text="Introduce un nombre";
                app.IntroduceunnombreLabel.FontColor="0.00,0.00,0.00";
                app.ComenzarentrenamientoButton.Enable=0;
                setValuesReadyPart2(app);
            end
        end

        % Button pushed function: ComenzarcapturadeaccionesButton
        function ComenzarcapturadeaccionesButtonPushed(app, event)
            ActividadLSTMTFG;
        end

        % Size changed function: ReconocimientoaccionesTab
        function ReconocimientoaccionesTabSizeChanged(app, event)
            app.ReconocimientoaccionesTab.Position;
            InicioLSTMTFG;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EntrenamientoredTab = uitab(app.TabGroup);
            app.EntrenamientoredTab.Title = 'Entrenamiento red';
            app.GridLayout = uigridlayout(app.EntrenamientoredTab);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.EntrenarredButton = uibutton(app.GridLayout2, 'push');
            app.EntrenarredButton.ButtonPushedFcn = createCallbackFcn(app, @EntrenarredButtonPushed, true);
            app.EntrenarredButton.Text = 'Entrenar red';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.ComenzarentrenamientoButton = uibutton(app.GridLayout3, 'push');
            app.ComenzarentrenamientoButton.ButtonPushedFcn = createCallbackFcn(app, @ComenzarentrenamientoButtonPushed, true);
            app.ComenzarentrenamientoButton.Enable = 'off';
            app.ComenzarentrenamientoButton.Text = 'Comenzar entrenamiento';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.NombreRedLabel = uilabel(app.GridLayout4);
            app.NombreRedLabel.Enable = 'off';
            app.NombreRedLabel.Text = 'Nombre de la red:';
            app.NumMovsLabel = uilabel(app.GridLayout4);
            app.NumMovsLabel.Text = 'Número de movimientos:';
            app.NombrearchivoLabel = uilabel(app.GridLayout4);
            app.NombrearchivoLabel.Enable = 'off';
            app.NombrearchivoLabel.Text = 'Nombre de archivo:';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.IntroduceunnombreLabel = uilabel(app.GridLayout5);
            app.IntroduceunnombreLabel.Enable = 'off';
            app.IntroduceunnombreLabel.Text = 'Introduce un nombre';
            app.numeroMovsEditFieldLabel = uilabel(app.GridLayout5);
            app.numeroMovsEditFieldLabel.Text = 'numeroMovsEditField';
            app.numeroMovsEditField = uieditfield(app.GridLayout5, 'numeric');
            app.numeroMovsEditField.Limits = [1 5];
            app.numeroMovsEditField.Value = 1;
            app.NombreRedEditFieldLabel = uilabel(app.GridLayout5);
            app.NombreRedEditFieldLabel.Enable = 'off';
            app.NombreRedEditFieldLabel.Text = 'NombreRedEdit Field';
            app.NombreRedEditField = uieditfield(app.GridLayout5, 'text');
            app.NombreRedEditField.Enable = 'off';
            app.EditFieldLabel = uilabel(app.GridLayout5);
            app.EditFieldLabel.Text = 'Edit Field';
            app.NombrearchivodatosEditField = uieditfield(app.GridLayout5, 'text');
            app.NombrearchivodatosEditField.Enable = 'off';
            app.GridLayout6 = uigridlayout(app.GridLayout3);
            app.BuscarButton = uibutton(app.GridLayout6, 'push');
            app.BuscarButton.ButtonPushedFcn = createCallbackFcn(app, @BuscarButtonPushed, true);
            app.BuscarButton.Enable = 'off';
            app.BuscarButton.Text = 'Buscar';
            app.AceptarButton = uibutton(app.GridLayout6, 'push');
            app.AceptarButton.ButtonPushedFcn = createCallbackFcn(app, @AceptarButtonPushed, true);
            app.AceptarButton.Text = 'Aceptar';
            app.AadirButton = uibutton(app.GridLayout6, 'push');
            app.AadirButton.ButtonPushedFcn = createCallbackFcn(app, @AadirButtonPushed, true);
            app.AadirButton.Enable = 'off';
            app.AadirButton.Text = 'Añadir';
            app.AceptarNombreRedButton = uibutton(app.GridLayout6, 'push');
            app.AceptarNombreRedButton.ButtonPushedFcn = createCallbackFcn(app, @AceptarNombreRedButtonPushed, true);
            app.AceptarNombreRedButton.Enable = 'off';
            app.AceptarNombreRedButton.Text = 'Aceptar';
            app.TituloLabel = uilabel(app.GridLayout3);
            app.TituloLabel.Text = 'CREAR RED CON MOVIMIENTOS PROPIOS';
            app.RESETButton = uibutton(app.GridLayout3, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Enable = 'off';
            app.RESETButton.Text = 'RESET';
            app.ReconocimientoaccionesTab = uitab(app.TabGroup);
            app.ReconocimientoaccionesTab.SizeChangedFcn = createCallbackFcn(app, @ReconocimientoaccionesTabSizeChanged, true);
            app.ReconocimientoaccionesTab.Title = 'Reconocimiento acciones';
            app.ComenzarcapturadeaccionesButton = uibutton(app.ReconocimientoaccionesTab, 'push');
            app.ComenzarcapturadeaccionesButton.ButtonPushedFcn = createCallbackFcn(app, @ComenzarcapturadeaccionesButtonPushed, true);
            app.ComenzarcapturadeaccionesButton.Text = 'Comenzar captura de acciones';
            app.Label = uilabel(app.ReconocimientoaccionesTab);
            app.Label.Text = 'Antes de comenzar la captura de acciones asegurese de activar los sensores en el dispositivo';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end