classdef popUpEntrenamiento < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        TextArea  matlab.ui.control.TextArea
        Label     matlab.ui.control.Label
        SIButton  matlab.ui.control.Button
        NOButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, nombres)
            app.CallingApp=mainapp;
            [m,n] = size(nombres);
            textonombres ="";
            for i = 1:n
               textonombres = textonombres+nombres(1,i)+" ";
            end
            app.TextArea.Value = "Se creará una red a partir de los archivos: " + textonombres;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Se creará una red a partir de los archivos:'};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '¿Quiere continuar? En caso de pulsar NO, se borrarán los archivos introducidos';
            app.SIButton = uibutton(app.UIFigure, 'push');
            app.SIButton.Text = 'SI';
            app.NOButton = uibutton(app.UIFigure, 'push');
            app.NOButton.Text = 'NO';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = popUpEntrenamiento(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end